classdef PreloadAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        FileMenu         matlab.ui.container.Menu
        SaveMenu         matlab.ui.container.Menu
        InitializePanel  matlab.ui.container.Panel
        ResetButton      matlab.ui.control.Button
        OpenLabel        matlab.ui.control.Label
        FitButton        matlab.ui.control.Button
        Button           matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        datafile % data file name
        datapath % data file path
        dataname % data name
        tdata % time data
        force % force data
        hLeg % plot legend handle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % import data file
            [file, path] = uigetfile({'*.csv';'*.xls'},...
                          'Select Data File');
            if isequal(file, 0)
                app.FitButton.Enable = "off";
            else
                app.datapath = path;
                app.datafile = file;
                app.dataname = strrep(file, '_', '');
                app.OpenLabel.Text = app.dataname;
                app.FitButton.Enable = "on";
            end
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            if (app.FitButton.Enable == true)
                T = readtable(fullfile(app.datapath, app.datafile));
                app.tdata = table2array(T(1,:));
                app.force = table2array(T(2,:));
    
                fun = @(x,time) x(1)*exp(x(2)*time)+x(3)*exp(x(4)*time);
                x0 = [200,-1, 200, -1];
    
                x = lsqcurvefit(fun, x0, app.tdata, app.force);
    
                datalabel = erase(app.dataname, '.csv');
                
                times = linspace(app.tdata(1),app.tdata(end));
                plot(app.UIAxes, app.tdata, app.force, 'ko', times, fun(x, times), 'b-');
                app.hLeg = legend(app.UIAxes, datalabel, 'Fitted exponential');
                title(app.UIAxes, strcat(datalabel, ' Data and Fitted Curve'));
                xlabel(app.UIAxes, 'sample'); % 270 seconds
                ylabel(app.UIAxes, '%force');

                app.ResetButton.Enable = "on";
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if (app.ResetButton.Enable == true)
                app.datafile = '';
                app.datapath = '';
                app.dataname = '';
                app.tdata = [];
                app.force = [];
                app.UIAxes.reset;
                set(app.hLeg, 'visible', 'off');
                app.OpenLabel.Text = "Open...";
                app.FitButton.Enable = "off";
                app.ResetButton.Enable = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.InitializePanel = uipanel(app.UIFigure);
            app.InitializePanel.Title = 'Initialize';
            app.Button = uibutton(app.InitializePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'open-folder.png');
            app.Button.Text = '';
            app.FitButton = uibutton(app.InitializePanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Enable = 'off';
            app.FitButton.Text = 'Fit';
            app.OpenLabel = uilabel(app.InitializePanel);
            app.OpenLabel.Text = 'Open...';
            app.ResetButton = uibutton(app.InitializePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PreloadAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end